classdef PredictiveMaintenanceDashboard_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        PredictionTextArea              matlab.ui.control.TextArea
        PredictionTextAreaLabel         matlab.ui.control.Label
        PredictFailureButton            matlab.ui.control.Button
        ToolWearFieldEditField          matlab.ui.control.NumericEditField
        ToolWearFieldEditFieldLabel     matlab.ui.control.Label
        TorqueFieldEditField            matlab.ui.control.NumericEditField
        TorqueFieldEditFieldLabel       matlab.ui.control.Label
        SpeedFieldEditField             matlab.ui.control.NumericEditField
        SpeedFieldEditFieldLabel        matlab.ui.control.Label
        ProcessTempFieldEditField       matlab.ui.control.NumericEditField
        ProcessTempFieldEditFieldLabel  matlab.ui.control.Label
        AirTempFieldEditField           matlab.ui.control.NumericEditField
        AirTempFieldEditFieldLabel      matlab.ui.control.Label
        ToolWearminLabel                matlab.ui.control.Label
        TorqueNmLabel                   matlab.ui.control.Label
        RotationalSpeedrpmLabel         matlab.ui.control.Label
        ProcessTemperatureKLabel        matlab.ui.control.Label
        AirTemperatureKLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    properties (Access = private)

        RandomForestModel

    end
    
    methods (Access = private)
        
        function startupFcn(app)
            modelFile = "../models/randomForestModel.mat";

            loadedModel = load(modelFile);

            app.RandomForestModel = loadedModel.randomForestModel;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictFailureButton
        function PredictFailureButtonPushed(app, event)
            % Load Random Forest model
            loadedModel = load("../models/randomForestModel.mat");

            randomForestModel = loadedModel.randomForestModel;


            % Collect sensor inputs
            sensorData = table( ...
                app.AirTempFieldEditField.Value,...
                app.ProcessTempFieldEditField.Value,...
                app.SpeedFieldEditField.Value,...
                app.TorqueFieldEditField.Value,...
                app.ToolWearFieldEditField.Value,...
                'VariableNames',{ ...
                'AirTemperature_K_', ...
                'ProcessTemperature_K_', ...
                'RotationalSpeed_rpm_', ...
                'Torque_Nm_', ...
                'ToolWear_min_'});


            % Predict
            [prediction,score] = predict(randomForestModel,sensorData);
            failureProbability = score(2) * 100;


            if prediction == 1

                % Failure condition
                app.StatusLamp.Color = 'red';

                app.PredictionTextArea.Value = {
                    '⚠ MACHINE FAILURE PREDICTED'
                    ['Failure Probability: ', num2str(failureProbability,'%.2f'), '%']
                    };


            else

                % Healthy condition
                app.StatusLamp.Color = 'green';

                app.PredictionTextArea.Value = {
                    '✅ MACHINE HEALTHY'
                    ['Failure Probability: ', num2str(failureProbability,'%.2f'), '%']
                    };

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Industrial AI Predictive Maintenance';
            app.AirTemperatureKLabel = uilabel(app.UIFigure);
            app.AirTemperatureKLabel.Text = '       Air Temperature (K)  ';
            app.ProcessTemperatureKLabel = uilabel(app.UIFigure);
            app.ProcessTemperatureKLabel.Text = '  Process Temperature (K)';
            app.RotationalSpeedrpmLabel = uilabel(app.UIFigure);
            app.RotationalSpeedrpmLabel.Text = '   Rotational Speed (rpm)';
            app.TorqueNmLabel = uilabel(app.UIFigure);
            app.TorqueNmLabel.Text = '          Torque (Nm)';
            app.ToolWearminLabel = uilabel(app.UIFigure);
            app.ToolWearminLabel.Text = '         Tool Wear (min)';
            app.AirTempFieldEditFieldLabel = uilabel(app.UIFigure);
            app.AirTempFieldEditFieldLabel.Text = 'AirTempField';
            app.AirTempFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcessTempFieldEditFieldLabel = uilabel(app.UIFigure);
            app.ProcessTempFieldEditFieldLabel.Text = 'ProcessTempField';
            app.ProcessTempFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedFieldEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedFieldEditFieldLabel.Text = 'SpeedField';
            app.SpeedFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.TorqueFieldEditFieldLabel = uilabel(app.UIFigure);
            app.TorqueFieldEditFieldLabel.Text = 'TorqueField';
            app.TorqueFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToolWearFieldEditFieldLabel = uilabel(app.UIFigure);
            app.ToolWearFieldEditFieldLabel.Text = 'ToolWearField';
            app.ToolWearFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredictFailureButton = uibutton(app.UIFigure, 'push');
            app.PredictFailureButton.ButtonPushedFcn = createCallbackFcn(app, @PredictFailureButtonPushed, true);
            app.PredictFailureButton.Text = 'Predict Failure';
            app.PredictionTextAreaLabel = uilabel(app.UIFigure);
            app.PredictionTextAreaLabel.Text = 'PredictionTextArea';
            app.PredictionTextArea = uitextarea(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'StatusLamp';
            app.StatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PredictiveMaintenanceDashboard_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end